classdef S3XYapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TeslaModelY  matlab.ui.control.UIAxes
        TeslaModel3  matlab.ui.control.UIAxes
        TeslaModelS  matlab.ui.control.UIAxes
        TeslaModelX  matlab.ui.control.UIAxes
        PlotButton   matlab.ui.control.Button
        Image2       matlab.ui.control.Image
        Image3       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function GodspeedButtonPushed(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            simout = sim("S3XY_Zero2Sixty.slx");
            plot(app.TeslaModelY, simout.ModelYVelocity.Time, simout.ModelYVelocity.Data);
            plot(app.TeslaModelX, simout.ModelXVelocity.Time, simout.ModelXVelocity.Data);
            plot(app.TeslaModel3, simout.Model3Velocity.Time, simout.Model3Velocity.Data);
            plot(app.TeslaModelS, simout.ModelSVelocity.Time, simout.ModelSVelocity.Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TeslaModelY = uiaxes(app.UIFigure);
            title(app.TeslaModelY, 'Tesla Model Y 0-60')
            xlabel(app.TeslaModelY, 'Time')
            ylabel(app.TeslaModelY, 'Speed (mph)')
            app.TeslaModelY.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.TeslaModelY.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.TeslaModelY.YTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80];
            app.TeslaModelY.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'};
            app.TeslaModel3 = uiaxes(app.UIFigure);
            title(app.TeslaModel3, 'Tesla Model 3 0-60')
            xlabel(app.TeslaModel3, 'Time')
            ylabel(app.TeslaModel3, 'Speed (mph)')
            app.TeslaModel3.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.TeslaModel3.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.TeslaModel3.YTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80];
            app.TeslaModel3.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'};
            app.TeslaModelS = uiaxes(app.UIFigure);
            title(app.TeslaModelS, 'Tesla Model S 0-60')
            xlabel(app.TeslaModelS, 'Time')
            ylabel(app.TeslaModelS, 'Speed (mph)')
            app.TeslaModelS.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.TeslaModelS.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.TeslaModelS.YTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80];
            app.TeslaModelS.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'};
            app.TeslaModelX = uiaxes(app.UIFigure);
            title(app.TeslaModelX, 'Tesla Model X 0-60')
            xlabel(app.TeslaModelX, 'Time')
            ylabel(app.TeslaModelX, 'Speed (mph)')
            app.TeslaModelX.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.TeslaModelX.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.TeslaModelX.YTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80];
            app.TeslaModelX.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Godspeed';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'TB Personal Brand Logo.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Tesla Logo.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = S3XYapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end